classdef app61 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SelectButtonGroup            matlab.ui.container.ButtonGroup
        PhaseButton                  matlab.ui.control.RadioButton
        MagnitudeButton              matlab.ui.control.RadioButton
        ACSweepOptionsLabel          matlab.ui.control.Label
        HoldonCheckBox               matlab.ui.control.CheckBox
        ShowButton                   matlab.ui.control.Button
        SelectQuantityDropDown       matlab.ui.control.DropDown
        SelectQuantityDropDownLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        callingapp % Description
        allX;
        wrt;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, take_inputs, allX, values)
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure,"center")
            app.UIFigure.Visible = 'on';   
            
            app.callingapp = take_inputs;
            app.allX = allX;
            app.wrt = values;
        end

        % Drop down opening function: SelectQuantityDropDown
        function SelectQuantityDropDownOpening(app, event)
            app.SelectQuantityDropDown.Items = dropdownmenu();
        end

        % Value changed function: SelectQuantityDropDown
        function SelectQuantityDropDownValueChanged(app, event)

        end

        % Button pushed function: ShowButton
        function ShowButtonPushed(app, event)
            expression = app.SelectQuantityDropDown.Value;
            val_of_exp = [];
            allX = app.allX;
            for i = 1:size(allX,2)
                val_of_exp = [val_of_exp whaccuwant(expression, allX(:,i), 0)];
            end
            if app.HoldonCheckBox.Value 
                hold on;
            else
                hold off;
            end
            
            if strcmpi(app.SelectButtonGroup.Buttons, "Magnitude") 
                semilogx(app.wrt, abs(val_of_exp));
                grid on;
            else
                semilogx(app.wrt, rad2deg(angle(val_of_exp)));
                grid on;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectQuantityDropDownLabel = uilabel(app.UIFigure);
            app.SelectQuantityDropDownLabel.Text = 'Select Quantity';
            app.SelectQuantityDropDown = uidropdown(app.UIFigure);
            app.SelectQuantityDropDown.Items = {'--Select--'};
            app.SelectQuantityDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SelectQuantityDropDownOpening, true);
            app.SelectQuantityDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectQuantityDropDownValueChanged, true);
            app.SelectQuantityDropDown.Placeholder = 'Voltage / Current';
            app.SelectQuantityDropDown.Value = '--Select--';
            app.ShowButton = uibutton(app.UIFigure, 'push');
            app.ShowButton.ButtonPushedFcn = createCallbackFcn(app, @ShowButtonPushed, true);
            app.ShowButton.Text = 'Show';
            app.HoldonCheckBox = uicheckbox(app.UIFigure);
            app.HoldonCheckBox.Text = 'Hold on';
            app.ACSweepOptionsLabel = uilabel(app.UIFigure);
            app.ACSweepOptionsLabel.Text = 'AC Sweep Options';
            app.SelectButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectButtonGroup.Title = 'Select ';
            app.MagnitudeButton = uiradiobutton(app.SelectButtonGroup);
            app.MagnitudeButton.Text = 'Magnitude';
            app.MagnitudeButton.Value = true;
            app.PhaseButton = uiradiobutton(app.SelectButtonGroup);
            app.PhaseButton.Text = 'Phase';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app61(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end