classdef app71 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        HoldonCheckBox         matlab.ui.control.CheckBox
        SimulateButton         matlab.ui.control.Button
        SINPRINTOPTIONSLabel   matlab.ui.control.Label
        QuantityDropDown       matlab.ui.control.DropDown
        QuantityDropDownLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        callingapp; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, take_inputs)
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure,"center")
            app.UIFigure.Visible = 'on';   
            
            app.callingapp = take_inputs;
        end

        % Drop down opening function: QuantityDropDown
        function QuantityDropDownOpening(app, event)
            app.QuantityDropDown.Items = dropdownmenu();
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            expression = app.QuantityDropDown.Value;
            X = solve_for(1,[]);
            value = whaccuwant(expression, X, 1000);
            mag = abs(value);
            phase = angle(value);
            
            t = 0:0.00001:0.0025;
            y = mag*sin(2*pi*1000*t + phase);
            
            if app.HoldonCheckBox.Value
                hold on;
            else
                hold off;
            end
            plot(t,y);
           
            xlabel("Time");
            ylabel("Chosen Expression");
            title("Plot options");
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.QuantityDropDownLabel = uilabel(app.UIFigure);
            app.QuantityDropDownLabel.Text = 'Quantity';
            app.QuantityDropDown = uidropdown(app.UIFigure);
            app.QuantityDropDown.Items = {'--Select--'};
            app.QuantityDropDown.DropDownOpeningFcn = createCallbackFcn(app, @QuantityDropDownOpening, true);
            app.QuantityDropDown.Value = '--Select--';
            app.SINPRINTOPTIONSLabel = uilabel(app.UIFigure);
            app.SINPRINTOPTIONSLabel.Text = 'SIN PRINT OPTIONS';
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.HoldonCheckBox = uicheckbox(app.UIFigure);
            app.HoldonCheckBox.Text = 'Hold on';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app71(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end