classdef app9 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        TextArea            matlab.ui.control.TextArea
        SimulateButton      matlab.ui.control.Button
        BiasPointButton     matlab.ui.control.Button
        ACSweepButton       matlab.ui.control.Button
        DCSweepButton       matlab.ui.control.Button
        BiasPointCheckBox   matlab.ui.control.CheckBox
        ACSweepCheckBox     matlab.ui.control.CheckBox
        DCSweepCheckBox     matlab.ui.control.CheckBox
        SetupAnalysisLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        auxapp % Description
        callingapp % Description
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, take_inputs)
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure,"center")
            app.UIFigure.Visible = 'on';   
            
            app.callingapp = take_inputs;
            global texts;
            texts = ["" ""];
            global AC_src;
            if length(AC_src) > 0
                app.DCSweepButton.Enable = 'Off';
                app.DCSweepCheckBox.Enable = 'Off';
                app.DCSweepCheckBox.Visible = 'Off';
            else
                app.ACSweepButton.Enable = 'Off';
                app.ACSweepCheckBox.Enable = 'Off';
                app.ACSweepCheckBox.Visible = 'Off';
            end
        end

        % Button pushed function: DCSweepButton
        function DCSweepButtonPushed(app, event)
            app.auxapp = app91(app);
        end

        % Button pushed function: ACSweepButton
        function ACSweepButtonPushed(app, event)
            app.auxapp = app92(app);
        end

        % Button pushed function: BiasPointButton
        function BiasPointButtonPushed(app, event)

        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            DC = app.DCSweepCheckBox.Value;
            AC = app.ACSweepCheckBox.Value;
            BP = app.BiasPointCheckBox.Value;
            global texts;
            global instructions;
            comments = [];
            
            if DC
                if ~strcmp(texts(1),"")
                    instructions = [instructions texts(1)];
                else
                    comments = [comments; "DC Sweep parameters undefined"];
                end
            end
            
            if AC
                if ~strcmp(texts(2),"")
                    instructions = [instructions texts(2)];
                else
                    comments = [comments; "AC Sweep parameters undefined"];
                end
            end
            
            if BP
               
                instructions = [instructions ".op"];
                instructions = [instructions ".print"];
            
                % comments = [comments; "OP and Print parameters undefined"];
             
            end
            
            if isempty(comments)
                comments = "All things appear to be in order";
            end
            
            app.TextArea.Value = comments;
            instruction_parsing(app.callingapp);
            pause(1);
            close(app.UIFigure);
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            value = app.TextArea.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SetupAnalysisLabel = uilabel(app.UIFigure);
            app.SetupAnalysisLabel.Text = 'Setup Analysis';
            app.DCSweepCheckBox = uicheckbox(app.UIFigure);
            app.DCSweepCheckBox.Text = 'DC Sweep';
            app.ACSweepCheckBox = uicheckbox(app.UIFigure);
            app.ACSweepCheckBox.Text = 'AC Sweep';
            app.BiasPointCheckBox = uicheckbox(app.UIFigure);
            app.BiasPointCheckBox.Text = 'Bias Point';
            app.DCSweepButton = uibutton(app.UIFigure, 'push');
            app.DCSweepButton.ButtonPushedFcn = createCallbackFcn(app, @DCSweepButtonPushed, true);
            app.DCSweepButton.Text = 'DC Sweep';
            app.ACSweepButton = uibutton(app.UIFigure, 'push');
            app.ACSweepButton.ButtonPushedFcn = createCallbackFcn(app, @ACSweepButtonPushed, true);
            app.ACSweepButton.Text = 'AC Sweep';
            app.BiasPointButton = uibutton(app.UIFigure, 'push');
            app.BiasPointButton.ButtonPushedFcn = createCallbackFcn(app, @BiasPointButtonPushed, true);
            app.BiasPointButton.Text = 'Bias Point';
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app9(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end