classdef take_inputs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        SIMULATEButton          matlab.ui.control.Button
        TextArea                matlab.ui.control.TextArea
        DonewritingButton       matlab.ui.control.Button
        GetinfofromButtonGroup  matlab.ui.container.ButtonGroup
        TextButton              matlab.ui.control.RadioButton
        FileButton              matlab.ui.control.RadioButton
        ImportButton            matlab.ui.control.Button
        FileNameEditField       matlab.ui.control.EditField
        FileNameEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        
        auxapp % Description
        callingapp % Description
    end
    
    methods (Access = public)
        
        function instruction_parsing(app)
        global content;
        global t1;
        global AC_src;
        global instructions;
            
            fid = fopen('instruction_evaluating.txt','a');
            op_encountered = false;
            op_X = [];
            print_encountered = false;
            no_print_var = false;
            probe_vars = [];
            
            for i = 1:length(instructions)
                ins = split(upper(instructions(i)));
                switch ins(1)
                    case ".DC"
                        % do a DC Sweep
                        if length(AC_src) > 0
                            fprintf(fid, "'.dc' not supported for AC variables\n");
                            fclose(fid);
                            app.auxapp = app2(app);
                            error("'.dc' not supported for AC variables");
                        end
                        % Call the DC Sweep Command wrt to the given variable %
                        var_name = ins(2);
                        if ~t1_look_up(var_name, t1.element(:))
                            fprintf(fid, "No variable by the name %s found\n", var_name);
                            fclose(fid);
                            app.auxapp = app2(app);
                            error("No variable by the name %s found", var_name);
                        end
                        fprintf(fid, "Doing a DC sweep wrt %s\n", var_name);
                        
                        startVal = double(parseval(ins(3)));
                        endVal = double(parseval(ins(4)));
                        interval = double(parseval(ins(5)));
                        values = startVal:interval:endVal;
                        
%                         allX = [];
%                         
%                         t1o = t1;
%                         idx = t1_look_up(var_name, t1.element(:));
                       
                        
                        allX = DCSweep(var_name, values);
                        
                        % Now open the probe window %
                        app.auxapp = app4(app, allX, values);
                        
                        
                    case ".PRINT"
                        want = ins(2:end);
                            
                        if length(want) == 0
                            % Get the drop down menu in the GUI
                            no_print_var = true;
                        else
                        
                            if length(AC_src) > 0
                                % Command (1) %
                                fprintf(fid, "Printing for AC\n");
                                X = solve_for(1, []);
                               
                                if print_encountered
                                    fid_p = fopen("printing.txt", "a");
                                else
                                    print_encountered = true;
                                    fid_p = fopen("printing.txt", "w");
                                end
                                
                                for k = 1:length(want)
                                    exp = regexprep(want(k), " *", "");
                                    value = whaccuwant(exp, X, 1000);
                                    
                                    if isempty(value)
                                        fprintf(fid, "Expression not detected - %s\n", want(k));
                                        fclose(fid);
                                        fclose(fid_p);
                                        app.auxapp = app2(app);
                                        error("Expression not detected - %s", want(k));
                                    end
                                    
                                    mag = abs(value);
                                    phase = rad2deg(angle(value));
                                    
                                    fprintf(fid_p, "%s = %0.4fsin(2*pi*f*t + %0.4f) for f = 1 kHz\n", exp, mag, phase); 
                                end
                                fclose(fid_p);
                            end
                       
                            % Netlist typically prints the avg values 
                            % Since we are taking pure sinusoids, netlist would've
                            % given 0, but we are printing the whole sinusoids
                        end
                
                        if op_encountered == true
                            % Command (0) %
                            fprintf(fid, "Printing for DC\n");
                            want = ins(2:end);
                            
                            if length(want) == 0
                                % Get the drop down menu in the GUI
                                no_print_var = true;
                            else
                                if print_encountered
                                    fid_p = fopen("printing.txt", "a");
                                else
                                    print_encountered = true;
                                    fid_p = fopen("printing.txt", "w");
                                end
                                
                                for k = 1:length(want)
                                    exp = regexprep(want(k), " *", "");
                                    value = whaccuwant(exp, op_X, 0);
                                    
                                    if isempty(value)
                                        fprintf(fid, "Expression not detected - %s\n", want(k));
                                        fclose(fid);
                                        fclose(fid_p);
                                        app.auxapp = app2(app);
                                        error("Expression not detected - %s", want(k));
                                    end
                                    fprintf(fid_p, "%s = %0.4f\n", exp, value); 
                                end 
                                fclose(fid_p);
                            end
                        else
                            fprintf(fid, "Could not print for DC as no '.op' detected\n");
                        end
                        
                        if no_print_var
                            if op_encountered   
                                
                                app.auxapp = app7(app, op_X);
                            end
                            
                            if length(AC_src) > 0
                                app.auxapp = app71(app);
                            end

                        else
                           app.auxapp = app8(app);
                        end
                        
                        
                    case ".PROBE"
                        % probe - determine which things appear in the window
                        if length(ins) == 1
                            % open a probe window with all the values in the
                            % dropdown
                        else
                            % Get the selected options in the dropdown
                            probe_vars = ins(2:end);
                            
                        end


                    case ".OP"
                        % Find the matrix A, X and Z for all DC sources
                        if length(ins) > 1
                            fprintf(fid, "%s command not detected\n", instructions(i));
                            fclose(fid);
                            app.auxapp = app2(app);
                            error(fid, "%s command not detected", instructions(i));
                        end
                        
                        fprintf(fid, "Doing a bias point analysis\n");
                        op_X = solve_for(0, []);
                 
                        op_encountered = true;


                    case ".AC"
                        % Do an AC sweep %
                        
                        if length(ins) == 1
                            fprintf(fid, "AC specs not specified");
                            fclose(fid);
                            app.auxapp = app2(app);
                            error(fid, "AC specs not specified");
                        end
                        
                        fprintf(fid, "Doing an AC Sweep\n");
                        
                        N = uint32(parseval(ins(3)));
                        startVal = double(parseval(ins(4)));
                        endVal = double(parseval(ins(5)));
                        
                        if ins(2) == "LIN"
                            values = linspace(startVal, endVal, N);
                        elseif ins(2) == "DEC"
                            values = logspace(log10(startVal), log10(endVal), N*10*log10(endVal/startVal));
                        elseif ins(2) == "OCT"
                            fprintf(fid, "AC Sweep not supported for octal\n");
                            fclose(fid);
                            app.auxapp = app2(app);
                            error("AC Sweep not supported for octal")
                        else
                            fprintf(fid, "%s not detected\n", ins(2));
                            fclose(fid);
                            app.auxapp = app2(app);
                            error("%s not detected\n", ins(2));
                        end
                        
                        allX_ac = ACSweep(values);
                        
                        if isempty(allX_ac)
                            fprintf(fid, "AC Sweep not done as no AC variables\n");
                            fclose(fid);
                            app.auxapp = app2(app);
                            error("AC Sweep not done as no AC variables")
                        end
                        % Now open a probe window %
                        if ins(2) == "DEC"
                            app.auxapp = app61(app, allX_ac, values);
                        else
                            app.auxapp = app6(app, allX_ac, values);
                        end
                        
                    case ".END"
                        fprintf(fid, "Ending simulation");
                        break;


                    otherwise
                        fprintf(fid, "%s command not detected\n", ins(1));
                        fclose(fid);
                        app.auxapp = app2(app);
                        error("%s command not detected", ins(1));
                end
            end
            fclose(fid);
            save probe_vars.mat;
            app.auxapp = app2(app);
        end
        
        function value_parsing(app)
            warning off
            
            
            instruction_empty = false;
            
            %% Reading data %%
            
            fn = read();
            
            fd1 = fopen(fn,'r');
            content_ = readlines(fn);
            for i = 1:length(content_)
                content_(i) = strtrim(content_(i));
            end
            
            fclose(fd1);
            
            fid = fopen('check.txt','w');
            
            content_ = regexprep(content_, '\n\n+', '\n');
            content_(all(strcmp(content_,""),2),:) = [];
            
            newfile = regexp(content_, '^[^;*-]\s*.*$', 'match');
            
            contentp = [];
            for i = 1:length(content_)
                if length(newfile{i}) == 0
                    continue;
                end
                contentp = [contentp; newfile{i}];
            end
            
            if isempty(contentp) 
                fprintf(fid,"no data\n");
                fclose(fid);
                error("no data");
            else
                fprintf(fid,"data reading...\n");
            end
            
            instructions_ = regexp(contentp, '[.][a-z_A-Z].*$', 'match');
            global instructions;
            instructions = [];
            for i = 1:length(instructions_)
                if length(instructions_{i}) == 0
                    continue;
                end
                instructions = [instructions; instructions_{i}];
            end
            
            if isempty(instructions)
                % go to "what do to want to do section"
                fprintf(fid,"instruction empty\n");
                instruction_empty = true;
            else
                fprintf(fid,"instruction reading...\n");
            end
            
            save instruction_empty.mat;
            
            contentp = regexp(contentp, '^[^.]\s*.*$', 'match');
            
            global content; 
            content = [];
            
            for i = 1:length(contentp)
                if length(contentp{i}) == 0
                    continue;
                end
                content = [content; contentp{i}];
            end
            
            if isempty(content)
                fprintf(fid,"no component found\n");
                fclose(fid);
                error('no components');
            else
                fprintf(fid,"component parsing...\n");
            end
            
            
            content = regexprep(content, '\s+', ' ');
            
            for i = 1:length(content)
                content(i) = upper(content(i));
            end
            
            global branch_cnt;
            global line_cnt;
            global num_rlc
            global num_ind
            global num_v
            global num_i
            global num_vcvs
            global num_vccs
            global num_cccs
            global num_ccvs
            global num_r
            global num_c
            
            
            num_r = 0;
            num_c = 0;
            num_rlc = 0; 
            num_ind = 0; 
            num_v = 0;
            num_i = 0;    
            
            
            num_vcvs = 0;     
            num_vccs = 0;
            num_cccs = 0;
            num_ccvs = 0;
            
            line_cnt = length(content);
            branch_cnt = 0;
            
            for i = 1:line_cnt
                temp = char(content(i));
                x = temp(1);
                tk_cnt = length(split(content(i)));
            
                if (x == 'R') || (x == 'L') || (x == 'C')
                    if tk_cnt ~= 4
                        fprintf(fid,"branch %d not formatted correctly, %s\n", i,content(i));
                        fclose(fid);
                        error("branch %d not formatted correctly, %s", i,content(i));
                    else
                        fprintf(fid,"impedance read in branch %d - %s\n", i,content(i));
                    end
                    num_rlc = num_rlc + 1;
                    branch_cnt = branch_cnt + 1;
                    if x == 'L'
                        num_ind = num_ind + 1;
                    elseif x == 'C'
                        num_c = num_c + 1;
                    else 
                        num_r = num_r + 1;
                    end
                elseif x == 'V'
                    temp = split(content(i));
                    if ((tk_cnt ~= 4) && ~( ((tk_cnt == 5) && (temp(4) == "DC"))  ||  ((tk_cnt == 6) && (temp(4) == "AC")) ))
                        fprintf(fid,"branch %d not formatted correctly, %s\n", i,content(i));
                        fclose(fid);
                        error("branch %d not formatted correctly, %s",i,content(i));
                    else
                        fprintf(fid,"voltage src read in branch %d\n", i);
                    end
                    num_v = num_v + 1;
                    branch_cnt = branch_cnt + 1;
                    
                elseif x == 'I'
                    temp = split(content(i));
                    if ((tk_cnt ~= 4) && ~( ((tk_cnt == 5) && (temp(4) == "DC"))  ||  ((tk_cnt == 6) && (temp(4) == "AC")) ))
                        fprintf(fid,"branch %d not formatted correctly, %s\n", i,content(i));
                        fclose(fid);
                        error("branch %d not formatted correctly, %s", i,content(i));
                    else
                        fprintf(fid,"current src read in branch %d\n", i);
                    end
                    num_i = num_i + 1;
                    branch_cnt = branch_cnt + 1;
                    
                elseif x == 'E'
                    if (tk_cnt ~= 6)
                        fprintf(fid,"branch %d not formatted correctly, %s\n", i,content(i));
                        fclose(fid);
                        error("branch %d not formatted correctly, %s", i,content(i));
                    else
                        fprintf(fid,"vcvs read in branch %d\n", i);
                    end
                    num_vcvs = num_vcvs + 1;
                    branch_cnt = branch_cnt + 1;
              
                elseif x == 'G'
                    if (tk_cnt ~= 6)
                        fprintf(fid,"branch %d not formatted correctly, %s\n", i,content(i));
                        fclose(fid);
                        error("branch %d not formatted correctly, %s", i,content(i));
                    else
                        fprintf(fid,"vccs read in branch %d\n", i);
                    end
                    num_vccs = num_vccs + 1;
                    branch_cnt = branch_cnt + 1;
                    
                elseif x == 'F'
                    if (tk_cnt ~= 5)
                        fprintf(fid,"branch %d not formatted correctly, %s\n", i,content(i));
                        fclose(fid);
                        error("branch %d not formatted correctly, %s", i,content(i));
                    else
                        fprintf(fid,"cccs read in branch %d\n", i);
                    end
                    num_cccs = num_cccs + 1;
                    branch_cnt = branch_cnt + 1;
                elseif x == 'H'
                    if (tk_cnt ~= 5)
                        fprintf(fid,"branch %d not formatted correctly, %s\n", i,content(i));
                        fclose(fid);
                        error("branch %d not formatted correctly, %s", i,content(i));
                    else
                        fprintf(fid,"ccvs read in branch %d\n", i);
                    end
                    num_ccvs = num_ccvs + 1;
                    branch_cnt = branch_cnt + 1;
                else
                    fprintf(fid,"unknown element type in branch %d, %s\n", i, content(i));
                    fclose(fid);
                    error("unknown element type in branch %d, %s", i, content(i));
                end
            end
            
            varNames = {'element','p_node','n_node','cp_node','cn_node','value','Vname','Type'};
            varTypes = {'string', 'uint32', 'uint32', 'uint32', 'uint32', 'double', 'string', 'string'};
            
            global t1
            global t2
            global AC_src
            global DC_src
            t1 = table('size', [0 8], 'VariableTypes', varTypes, 'VariableNames', varNames);
            t2 = table('size', [0 3], 'VariableTypes', {'string', 'uint32', 'uint32'}, 'VariableNames', {'element','p_node','n_node'});
            AC_src = [];
            DC_src = [];
            
            for i = 1:line_cnt 
                temp = char(content(i));
                x = temp(1);
            
                if (x == 'R') || (x == 'L') || (x == 'C') 
                    rlc_element(i);
                 elseif (x == 'V') || (x == 'I') 
                    type = indep_source(i);
                    if type == 1
                        AC_src = [AC_src i];
                    else
                        DC_src = [DC_src i];
                    end
                 elseif x == 'E' 
                    vcvs_sub_network(i);
                 elseif x == 'G' 
                    vccs_sub_network(i);
                 elseif x == 'F' 
                    cccs_sub_network(i);
                 elseif x == 'H' 
                    ccvs_sub_network(i);
                else 
                    fprintf(fid,"unknown element type in branch %d, %s\n", i, content(i));
                    fclose(fid);
                    error("unknown element type in branch %d, %s",i,content(i));
                end
            end
            
            if findDuplications()
                fprintf(fid,"Elements cannot have the same name\n");
                fclose(fid);
                error("Elements cannot have the same name");
            else
                fprintf(fid,"Unique Names detected..\n");
            end
            
            num_nodes = count_nodes();
            global i_unk
            i_unk = num_v + num_vcvs + num_ccvs + num_ind;
            
            for i = 1:size(t1,1)
                temp = char(t1.element(i));
                x = temp(1);
                
                if (x == 'I') || (x == 'V') || (x == 'E') || (x == 'H') || (x == 'F')
                    t2.element(i) = t1.element(i);
                    t2.p_node(i) = t1.p_node(i);
                    t2.n_node(i) = t1.n_node(i);
                end
            end
            
            fprintf(fid,"All values appears to be in order..\n");
            fclose(fid);

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, project_main)
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure,"center")
            app.UIFigure.Visible = 'on';   
            
            app.callingapp = project_main;
            selectedButton = app.GetinfofromButtonGroup.SelectedObject;
            if strcmpi(selectedButton.Text, 'File')
                app.FileNameEditField.Enable = 'On';
                app.ImportButton.Enable = 'On';
                app.TextArea.Enable = 'Off';
                app.DonewritingButton.Enable = 'Off';
            else
                app.FileNameEditField.Enable = 'Off';
                app.ImportButton.Enable = 'Off';
                app.TextArea.Enable = 'On';
                app.DonewritingButton.Enable = 'On';
            end
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            [filename, pathname] = uigetfile('*.txt');
            app.FileNameEditField.Value = filename;
            save filename.mat;
            save pathname.mat;
            figure(app.UIFigure());
        end

        % Value changed function: FileNameEditField
        function FileNameEditFieldValueChanged(app, event)
            app.FileNameEditField.Editable = 'Off';
        end

        % Selection changed function: GetinfofromButtonGroup
        function GetinfofromButtonGroupSelectionChanged(app, event)
            selectedButton = app.GetinfofromButtonGroup.SelectedObject;
            clicked = selectedButton.Text;
            
            if strcmpi(selectedButton.Text, 'File')
                app.FileNameEditField.Enable = 'On';
                app.ImportButton.Enable = 'On';
                app.TextArea.Enable = 'Off';
                app.DonewritingButton.Enable = 'Off';
            else
                app.FileNameEditField.Enable = 'Off';
                app.ImportButton.Enable = 'Off';
                app.TextArea.Enable = 'On';
                app.DonewritingButton.Enable = 'On';
            end
            save clicked.mat
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)

            
        end

        % Button pushed function: DonewritingButton
        function DonewritingButtonPushed(app, event)
            value = app.TextArea.Value
            fid = fopen("textbox.txt", "w");
            for i = 1:length(value)
                fprintf(fid, value{i});
                fprintf(fid, "\n");
            end
            fclose(fid);
            %type 'textbox.txt';
        end

        % Button pushed function: SIMULATEButton
        function SIMULATEButtonPushed(app, event)
            value_parsing(app);
            app.auxapp = app3(app);
            netlist_print();
            app.auxapp = app5(app);
            load instruction_empty.mat;
            if instruction_empty
                fid = fopen('instruction_evaluating.txt','w');
                fprintf(fid, "No instruction detected\n");
                fprintf(fid, "Opening graphical window to take inputs\n");
                fclose(fid);
                app.auxapp = app9(app);
            else
                fid = fopen('instruction_evaluating.txt','w');
                fprintf(fid, "Instruction(s) detected\n");
                fclose(fid);
                instruction_parsing(app);
            end

        end

        % Value changing function: FileNameEditField
        function FileNameEditFieldValueChanging(app, event)
            changingValue = event.Value;
            app.FileNameEditField.ValueChangingFcn = changingValue;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.UIFigure, 'text');
            app.FileNameEditField.ValueChangedFcn = createCallbackFcn(app, @FileNameEditFieldValueChanged, true);
            app.FileNameEditField.ValueChangingFcn = createCallbackFcn(app, @FileNameEditFieldValueChanging, true);
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.GetinfofromButtonGroup = uibuttongroup(app.UIFigure);
            app.GetinfofromButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GetinfofromButtonGroupSelectionChanged, true);
            app.GetinfofromButtonGroup.Title = 'Get info from';
            app.FileButton = uiradiobutton(app.GetinfofromButtonGroup);
            app.FileButton.Text = 'File';
            app.FileButton.Value = true;
            app.TextButton = uiradiobutton(app.GetinfofromButtonGroup);
            app.TextButton.Text = 'Text';
            app.DonewritingButton = uibutton(app.UIFigure, 'push');
            app.DonewritingButton.ButtonPushedFcn = createCallbackFcn(app, @DonewritingButtonPushed, true);
            app.DonewritingButton.Text = 'Done writing';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.SIMULATEButton = uibutton(app.UIFigure, 'push');
            app.SIMULATEButton.ButtonPushedFcn = createCallbackFcn(app, @SIMULATEButtonPushed, true);
            app.SIMULATEButton.Text = 'SIMULATE';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = take_inputs(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end