classdef app92 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        FordecadegivepointsperdecadeLabel  matlab.ui.control.Label
        TypeButtonGroup           matlab.ui.container.ButtonGroup
        DecadeButton              matlab.ui.control.RadioButton
        LinearButton              matlab.ui.control.RadioButton
        ACSweepSetupLabel         matlab.ui.control.Label
        DoneButton                matlab.ui.control.Button
        IncrementEditField        matlab.ui.control.NumericEditField
        IncrementEditFieldLabel   matlab.ui.control.Label
        EndValueEditField         matlab.ui.control.NumericEditField
        EndValueEditFieldLabel    matlab.ui.control.Label
        StartValueEditField       matlab.ui.control.NumericEditField
        StartValueEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        callingapp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, app9)
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure,"center")
            app.UIFigure.Visible = 'on';   
            
            app.callingapp = app9;
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            if strcmpi(app.TypeButtonGroup.Buttons, "Linear")
                sweep_type = "LIN";
            else
                sweep_type = "DEC";
            end
            
            ins = ".ac " + sweep_type + " " + string(app.IncrementEditField.Value) + " " + string(app.StartValueEditField.Value) + " " + string(app.EndValueEditField.Value);
            global texts;
            texts(2) = ins;
            close(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartValueEditFieldLabel = uilabel(app.UIFigure);
            app.StartValueEditFieldLabel.Text = 'Start Value';
            app.StartValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.EndValueEditFieldLabel = uilabel(app.UIFigure);
            app.EndValueEditFieldLabel.Text = 'End Value';
            app.EndValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.IncrementEditFieldLabel = uilabel(app.UIFigure);
            app.IncrementEditFieldLabel.Text = 'Increment*';
            app.IncrementEditField = uieditfield(app.UIFigure, 'numeric');
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.ACSweepSetupLabel = uilabel(app.UIFigure);
            app.ACSweepSetupLabel.Text = 'AC Sweep Setup';
            app.TypeButtonGroup = uibuttongroup(app.UIFigure);
            app.TypeButtonGroup.Title = 'Type';
            app.LinearButton = uiradiobutton(app.TypeButtonGroup);
            app.LinearButton.Text = 'Linear';
            app.LinearButton.Value = true;
            app.DecadeButton = uiradiobutton(app.TypeButtonGroup);
            app.DecadeButton.Text = 'Decade';
            app.FordecadegivepointsperdecadeLabel = uilabel(app.UIFigure);
            app.FordecadegivepointsperdecadeLabel.Text = '*For decade give points per decade';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app92(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end